classdef AnimationApp1_GraphRescale < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        UIAxes       matlab.ui.control.UIAxes
        SliderLabel  matlab.ui.control.Label
        Slider       matlab.ui.control.Slider
        PlayButton   matlab.ui.control.Button
    end


    properties (Access = private)
        graphScale = 1;
        stride = 0.01;
        T = 1;
        Fs = 500;
        duration = 2*pi;
        tMatDepth;
        t;
        NoOfRepeat = 5;
        x;
        NoOfRepetitions = 5;
        
    end

    methods (Access = private)  
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.duration = app.duration*app.NoOfRepetitions;
            app.tMatDepth = floor((app.duration-app.T)/app.stride);
            app.t = zeros(app.Fs*app.T,app.tMatDepth);
            
            for i = 1:app.tMatDepth
                j=i-1;
                app.t(:,i) = 0.2*linspace(0+(j*app.stride),app.T+(j*app.stride),app.T*app.Fs);
            end
            
            app.x = sin(2*pi*app.t);
            
            plot(app.UIAxes,app.t(:,1),app.x(:,1).*app.graphScale)
        end

        % Value changing function: Slider
        function SliderValueChanging(app, event)
            app.graphScale = event.Value;
            
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            for i = (0:size(app.x,2))+1
                app.x(:,i) = app.x(:,i)*app.graphScale;
                plot(app.UIAxes,app.t(:,i),app.x(:,i))
                pause(app.stride)
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes.YLim = [-1 1];
            app.SliderLabel = uilabel(app.UIFigure);
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.UIFigure);
            app.Slider.Limits = [0 1];
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.Slider.Value = 1;
            app.PlayButton = uibutton(app.UIFigure, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
        end
    end

    methods (Access = public)

        % Construct app
        function app = AnimationApp1_GraphRescale

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end